classdef code2D_advance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClearFieldsButton            matlab.ui.control.Button
        UnitsDropDown                matlab.ui.control.DropDown
        UnitsDropDownLabel           matlab.ui.control.Label
        SavePlotsButton              matlab.ui.control.Button
        TabGroup2                    matlab.ui.container.TabGroup
        MaterialTab                  matlab.ui.container.Tab
        LengthLmEditField            matlab.ui.control.NumericEditField
        LengthLmLabel                matlab.ui.control.Label
        YoungsModulusENmEditField    matlab.ui.control.NumericEditField
        YoungsModulusENmm2Label      matlab.ui.control.Label
        MATERIALDropDown             matlab.ui.control.DropDown
        MATERIALDropDownLabel        matlab.ui.control.Label
        CrossSectionTab              matlab.ui.container.Tab
        rmEditField                  matlab.ui.control.NumericEditField
        rmEditFieldLabel             matlab.ui.control.Label
        MomentofInertiaImEditField   matlab.ui.control.NumericEditField
        MomentofInertiaImEditFieldLabel  matlab.ui.control.Label
        amEditField                  matlab.ui.control.NumericEditField
        amEditFieldLabel             matlab.ui.control.Label
        bmEditField                  matlab.ui.control.NumericEditField
        bmEditFieldLabel             matlab.ui.control.Label
        hmEditField                  matlab.ui.control.NumericEditField
        hmEditFieldLabel             matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        XsectionDropDown             matlab.ui.control.DropDown
        LoadConfigurationTab         matlab.ui.container.Tab
        Slider_4                     matlab.ui.control.Slider
        Image2                       matlab.ui.control.Image
        ForcefNEditField_2           matlab.ui.control.NumericEditField
        ForcefNEditField_2Label      matlab.ui.control.Label
        Slider_3                     matlab.ui.control.Slider
        ForcefNEditField             matlab.ui.control.NumericEditField
        ForcefNEditFieldLabel        matlab.ui.control.Label
        Slider_2                     matlab.ui.control.Slider
        DistributedLoadFNmEditField  matlab.ui.control.NumericEditField
        DistributedLoadF1NmLabel     matlab.ui.control.Label
        Slider                       matlab.ui.control.Slider
        MomentMNmEditField           matlab.ui.control.NumericEditField
        MomentMNm2Label              matlab.ui.control.Label
        ResetPlotsButton             matlab.ui.control.Button
        RunSimulationButton          matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        DeflectionTab                matlab.ui.container.Tab
        DeformationSwitch            matlab.ui.control.RockerSwitch
        DeformationSwitchLabel       matlab.ui.control.Label
        ShearArrowsSwitch            matlab.ui.control.RockerSwitch
        ShearArrowsLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ShearForceDiagramTab         matlab.ui.container.Tab
        UIAxes_2                     matlab.ui.control.UIAxes
        BendingMomentDiagramTab      matlab.ui.container.Tab
        UIAxes_3                     matlab.ui.control.UIAxes
        DSTATICRODMODELGUILabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        unit char = 'MKS'  % unit
        mat % material
    end
    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Helper function to convert the units to MKS
        function [L,E,M,F1,f1,f3,a,r,h,b,I] = convertToMKS(~,unit,L,E,M,F1,f1,f3,a,r,h,b,I)
            switch unit
                case 'MKS'
                    % already MKS, no change
                case 'IPS' % inches→meters, psi→Pa, etc.
                    L  = L * 0.0254;
                    a  = a * 0.0254;  r = r * 0.0254;  h = h * 0.0254;  b = b * 0.0254;
                    E  = E * 6894.76;             % psi → Pa
                    M  = M * 0.112985;            % lbf·in → N·m
                    F1 = F1 * 175.126;            % lbf/in → N/m
                    f1 = f1 * 4.44822;  f3 = f3 * 4.44822; % lbf → N
                    I  = I * (0.0254^4);          % in^4 → m^4
             
                case 'CGS' % cm→m, dyne→N
                    L  = L / 100;
                    a  = a / 100; r = r / 100; h = h / 100; b = b / 100;
                    E  = E * 0.1;                 % dyne/cm^2 → Pa
                    M  = M * 1e-7;                % dyne·cm → N·m
                    F1 = F1 * 0.1;                % dyne/cm → N/m
                    f1 = f1 * 1e-5; f3 = f3 * 1e-5;
                    I  = I * (1e-8);              % cm^4 → m^4
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Helper function to convert the units from MKS
        function [L,E,M,F1,f1,f3,a,r,h,b,I] = convertFromMKS(~,unit,L,E,M,F1,f1,f3,a,r,h,b,I)
            switch unit
                case 'MKS'
                    % no change
                case 'IPS'
                    L  = L / 0.0254;
                    a  = a / 0.0254;  r = r / 0.0254;  h = h / 0.0254;  b = b / 0.0254;
                    E  = E / 6894.76;
                    M  = M / 0.112985;
                    F1 = F1 / 175.126;
                    f1 = f1 / 4.44822; f3 = f3 / 4.44822;
                    I  = I / (0.0254^4);
             
                case 'CGS'
                    L  = L * 100;
                    a  = a * 100; r = r * 100; h = h * 100; b = b * 100;
                    E  = E / 0.1;
                    M  = M / 1e-7;
                    F1 = F1 / 0.1;
                    f1 = f1 / 1e-5; f3 = f3 / 1e-5;
                    I  = I / 1e-8;    
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Helper function for plot labels
        function [xlab, y1lab, y2lab, y3lab] = getAxisLabels(~,unit)
            switch unit
                case 'MKS'
                    xlab = 'Length (m)';  y1lab = 'Deflection (m)'; y2lab = 'Force (N)'; y3lab = 'Bending moment (N.m)';
                case 'IPS'
                    xlab = 'Length (in)'; y1lab = 'Deflection (in)'; y2lab = 'Force (lbf)'; y3lab = 'Bending moment (lbf.in)';
                case 'CGS'
                    xlab = 'Length (cm)'; y1lab = 'Deflection (cm)'; y2lab = 'Force (dyne)'; y3lab = 'Bending moment (dyne.cm)';
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Helper function to update slider limits safely
        function updateSliderLimitswithmean(~, meanVal, slider, ~)
            % Updates slider limits and value based on mean value
            % Conditions:
            % - If meanVal > 0: limits = [0, 2*meanVal]
            % - If meanVal < 0: limits = [2*meanVal, 0]
            % - If meanVal == 0: limits = [0, 1], value = 0
            % For all nonzero means, the slider value is set to meanVal
        
            %slider.Limits = [0, 1];
            if meanVal > 0
                %newLimits = [0, 2 * meanVal];
                slider.Limits = [0, 2 * meanVal];
                slider.Value = meanVal;
            elseif meanVal < 0
                %newLimits = [2 * meanVal, 0];
                slider.Limits = [2 * meanVal, 0];
                slider.Value = meanVal;
            else
                %newLimits = [0, 1];  % default limits when mean is zero    % set slider value to zero
                slider.Limits = [0, 1];
                slider.Value = 0; % set slider value 
            end
            %slider.Limits = newLimits;
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize required variables or state of the buttons.
            app.RunSimulationButton.Enable = 'off';
            app.UnitsDropDown.Value ='MKS';
            app.XsectionDropDown.Value='Select';
            app.MATERIALDropDown.Value='Select';
            cla(app.UIAxes,"reset");
            cla(app.UIAxes_2,"reset");
            cla(app.UIAxes_3,"reset");
            legend(app.UIAxes, 'off');
        end

        % Callback function
        function DeflectionButtonPushed(app, event)
            % This button displays the deflection curve.
            
        end

        % Callback function
        function SFDButtonPushed(app, event)
            % This button displays the SFD
            
        end

        % Callback function
        function BMDButtonPushed(app, event)
            % This button displays BMD
            
        end

        % Value changed function: MATERIALDropDown
        function MATERIALDropDownValueChanged(app, event)
            app.mat = app.MATERIALDropDown.Value;
            switch app.mat
                case 'Structural Steel'
                    app.YoungsModulusENmEditField.Value = 2.10e11;     % Pa (210 GPa)
                case 'Aluminum Alloy'
                    app.YoungsModulusENmEditField.Value  = 7.0e10;      % Pa (70 GPa)
                case 'Stainless Steel (304)'
                    app.YoungsModulusENmEditField.Value  = 1.90e11;     % Pa (190 GPa)
                case 'Titanium Alloy (Ti-6Al-4V)'
                    app.YoungsModulusENmEditField.Value  = 1.20e11;     % Pa (120 GPa)
            end
        end

        % Callback function: RunSimulationButton, UIAxes, UIAxes_2,
        % UIAxes_3
        function RUNSIMULATIONButtonPushed(app, event)
          
            % Run simulation will run all the plots.
            % Tab Deflection
            cla(app.UIAxes,'reset');
            
            % Get input values
            E = app.YoungsModulusENmEditField.Value;
            I = app.MomentofInertiaImEditField.Value;
            Length = app.LengthLmEditField.Value;
            
            M  = app.Slider.Value;
            f1 = app.Slider_3.Value;
            f3 = app.Slider_4.Value;
            wf = app.Slider_2.Value;
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            kappa = M/(E*I);
            % Define ODE
            f = @(distance, x) [-x(2)/(E*I); -x(3)*x(1)-wf; x(2)*x(1)];
            
            % Initial conditions: [kappa, f1, f3]
            % f1=shear force, f3=axial force
            [distance, xa] = ode45(f, [0 Length], [kappa f1 f3]);

            len = length(xa);

            % Reversing distance and kappa for integration from clamped end
            s = Length * ones(len, 1) - flipud(distance);
            kappa_final = flipud(xa(:,1));

            % Calculate linear deflection
            le=0:Length/20:Length;
            linear_deflection = ((-f1 * (le.^2).*(3.*Length-le)) ./ (6*E*I))+((kappa/2).*le.*le)+(wf.*le.^2.*(6*Length^2-4*Length.*le+le.^2)/(24*E*I));

            % Calculate phi, Rx, Ry
            phi = cumtrapz(s, kappa_final);
            Rx = cumtrapz(s, cos(phi));
            Ry = cumtrapz(s, sin(phi));

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Find absolute maximum deflection value
            del_max_l = max(abs(linear_deflection));
            del_max_nl = max(abs(Ry));
            % Find indices of maximum deflection value (both signs)
            ind_l = find(linear_deflection == del_max_l | linear_deflection == -del_max_l);
            ind_nl = find(Ry == del_max_nl | Ry == -del_max_nl);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %Shear values for nonlinear and linear case
            shear = flipud(xa(:,2));
            linear_shear = f1 * ones(1, length(le)) - fliplr(wf * le);

            % Reversing distance and curvature
            moment = (E*I)*flipud(xa(:,1));

            % Calculate moment for linear theory
            le = 0:Length/20:Length;
            linear_moment = M * ones(1, length(le)) - fliplr(f1*le) + fliplr((wf*le.^2)/2);


            % Get axis labels
            [xlab, y1lab, y2lab, y3lab] = getAxisLabels(app,app.unit);

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Tab Deflection PLot
            % Plot Rx vs Ry with max deflection values.
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, Rx, Ry, 'LineWidth', 2);
            plot(app.UIAxes, le, linear_deflection, '--g', 'LineWidth', 2);
            % Highlight max delta point(s)
            plot(app.UIAxes, Rx(ind_nl), Ry(ind_nl), 'LineStyle', 'none', ...
                'Color', 'b', 'Marker', 'x', 'MarkerSize',8, 'LineWidth', 2);
            plot(app.UIAxes, le(ind_l), linear_deflection(ind_l), 'LineStyle', 'none', ...
                'Color', 'g', 'Marker', 'o', 'MarkerSize',8, 'LineWidth', 2);
            leg = legend(app.UIAxes, 'NonLinear Theory', 'Small Deflection Theory', ...
                ['$|\delta_{max,nl}|=', num2str(del_max_nl),'$'], ['$|\delta_{max,l}|=', ...
                num2str(del_max_l),'$'], 'Orientation', 'vertical','NumColumns', 1,'Location','northeastoutside');
            set(leg,'Interpreter','latex')
            % leg.Position(1) = 0.70;
            % leg.Position(2) = 0.79;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % --- Shear overlay only if switch is ON ---
            % Switch-case for arrows
            %To use these quiver plots in both if/else statements
            persistent q_shear q_l_shear
            
            %Toggle on
            switch app.ShearArrowsSwitch.Value
                case 'Show'
                %Take a few instences to plot the quiver on
                ind1=1:2:length(Rx);
                ind2=1:2:length(le);
                %Normalize the shear force max arrow to be 4% of length
                val1 = 0.04*Length.*shear(ind1)./max(abs(shear));
                val2 = 0.04*Length.*linear_shear(ind2)./max(abs(linear_shear));
                
                q_shear = quiver(app.UIAxes, Rx(ind1),Ry(ind1), ...
                    abs(val1).*cos(phi(ind1)+sign(val1).*pi/2), ...
                    abs(val1).*sin(phi(ind1)+sign(val1).*pi/2), ...
                    0,'LineWidth',1,'Color','b','MaxHeadSize',0.1,HandleVisibility='off');  
                %Hide the legend
                %q_shear.Annotation.LegendInformation.IconDisplayStyle = 'off';
                q_l_shear = quiver(app.UIAxes, le(ind2),linear_deflection(ind2), ...
                    zeros(1,length(le(ind2))), val2, ...
                    0,'LineWidth',1,'Color','g','MaxHeadSize',0.1,HandleVisibility='off');                   
                %q_l_shear.Annotation.LegendInformation.IconDisplayStyle = 'off';
                hold(app.UIAxes, 'off');
                %Toggle Off
                case 'Hide'
                %Delete the quiver plots
                delete(q_shear)
                delete(q_l_shear)
                hold(app.UIAxes, 'off');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            title(app.UIAxes, 'Beam Deflection');
            xlabel(app.UIAxes, xlab);
            ylabel(app.UIAxes, y1lab);
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'on');
            box(app.UIAxes, 'on');

            if strcmp(app.DeformationSwitch.Value, 'True')
                xlim(app.UIAxes,[-Length Length])
                ylim(app.UIAxes, [-Length Length])
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Tab SFD
            cla(app.UIAxes_2);
            
            % Find absolute maxima for shead force
            shear_max_l = max(abs(linear_shear));
            shear_max_nl = max(abs(shear));
            % Find indices of maximum deflection value (both signs)
            ind_l = find(linear_shear == shear_max_l | linear_shear == -shear_max_l);
            ind_nl = find(shear == shear_max_nl | shear == -shear_max_nl);

            hold(app.UIAxes_2, 'on');
            plot(app.UIAxes_2, s, shear,'-b', 'LineWidth', 2);
            plot(app.UIAxes_2, le, linear_shear, '--g', 'LineWidth', 2);

            % Highlight max shear point(s)
            plot(app.UIAxes_2, s(ind_nl), shear(ind_nl), 'LineStyle', 'none', ...
                'Color', 'b', 'Marker', 'x', 'MarkerSize',8, 'LineWidth', 2);
            plot(app.UIAxes_2, le(ind_l), linear_shear(ind_l), 'LineStyle', 'none', ...
                'Color', 'g', 'Marker', 'o', 'MarkerSize',8, 'LineWidth', 2);

            title(app.UIAxes_2, 'Shear Force Diagram');
            xlabel(app.UIAxes_2, xlab);
            ylabel(app.UIAxes_2, y2lab);
            grid(app.UIAxes_2, 'on');
            axis(app.UIAxes_2, 'on');
            box(app.UIAxes_2, 'on');
            leg = legend(app.UIAxes_2, 'NonLinear Theory', 'Small Deflection Theory' , ...
                ['$|V_{max,nl}|=', num2str(shear_max_nl),'$'], ['$|V_{max,l}|=', ...
                num2str(shear_max_l),'$'], 'Orientation', 'vertical','NumColumns', 1,'Location','northeastoutside');
            set(leg,'Interpreter','latex');
            % leg.Position(1) = 0.70;
            % leg.Position(2) = 0.79;
            hold(app.UIAxes_2, 'off');
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Tab Bending Moment Diagram
            cla(app.UIAxes_3);
            
            % Find absolute maxima for moment
            moment_max_l = max(abs(linear_moment));
            moment_max_nl = max(abs(moment));
            % Find indices of maximum deflection value (both signs)
            ind_l = find(linear_moment == moment_max_l | linear_moment == -moment_max_l);
            ind_nl = find(moment == moment_max_nl | moment == -moment_max_nl);

            hold(app.UIAxes_3, 'on');
            plot(app.UIAxes_3, s, moment, 'LineWidth', 2);
            plot(app.UIAxes_3, le, linear_moment, '--g', 'LineWidth', 2);

            % Highlight max moment point(s)
            plot(app.UIAxes_3, s(ind_nl), moment(ind_nl), 'LineStyle', 'none', ...
                'Color', 'b', 'Marker', 'x', 'MarkerSize',8, 'LineWidth', 2);
            plot(app.UIAxes_3, le(ind_l), linear_moment(ind_l), 'LineStyle', 'none', ...
                'Color', 'g', 'Marker', 'o', 'MarkerSize',8, 'LineWidth', 2);

            title(app.UIAxes_3, 'Bending Moment Diagram');
            xlabel(app.UIAxes_3, xlab);
            ylabel(app.UIAxes_3, y3lab);
            grid(app.UIAxes_3, 'on');
            axis(app.UIAxes_3, 'on');
            box(app.UIAxes_3, 'on');
            leg = legend(app.UIAxes_3, 'NonLinear Theory', 'Small Deflection Theory' , ...
                ['$|M_{max,nl}|=', num2str(moment_max_nl),'$'], ['$|M_{max,l}|=', ...
                num2str(moment_max_l),'$'], 'Orientation', 'vertical','NumColumns', 1,'Location','northeastoutside');
            set(leg,'Interpreter','latex');
            % leg.Position(1) = 0.70;
            % leg.Position(2) = 0.79;
            hold(app.UIAxes_3, 'off');

        end

        % Button pushed function: ResetPlotsButton
        function ResetPlotsButtonPushed(app, event)
            % reset all the plots
            % Clear the plots and reset inputs
            cla(app.UIAxes,"reset");
            cla(app.UIAxes_2,"reset");
            cla(app.UIAxes_3,"reset");
            
            legend(app.UIAxes, 'off');
        end

        % Value changed function: YoungsModulusENmEditField
        function YoungsModulusENmEditFieldValueChanged(app, event)
           
        end

        % Value changed function: LengthLmEditField
        function LengthLmEditFieldValueChanged(app, event)

            if app.LengthLmEditField.Value <= 0
                app.RunSimulationButton.Enable = 'off';
            else
                app.RunSimulationButton.Enable = 'on';
            end
        end

        % Value changed function: hmEditField
        function hmEditFieldValueChanged(app, event)
           
        end

        % Value changed function: bmEditField
        function bmEditFieldValueChanged(app, event)
            b = app.bmEditField.Value;
            h = app.hmEditField.Value;
            app.MomentofInertiaImEditField.Value = (b*h^3)/12;
        end

        % Value changed function: amEditField
        function amEditFieldValueChanged(app, event)
            a = app.amEditField.Value;
            app.MomentofInertiaImEditField.Value = (a^4)/12;
            
        end

        % Value changed function: MomentofInertiaImEditField
        function MomentofInertiaImEditFieldValueChanged(app, event)
            I = app.MomentofInertiaImEditField.Value;
            if I < 0
                uialert(app.UIFigure, 'Moment of Inertia should be positive', 'Invalid Input');
                app.MomentofInertiaImEditField.Value = 0;
            end
        end

        % Callback function: Image, XsectionDropDown
        function XsectionDropDownValueChanged(app, event)
            section = app.XsectionDropDown.Value;
            switch section
                case 'Square'
                    app.Image.ImageSource = 'Square.png';
                    app.amEditField.Enable = 'on';
                    app.bmEditField.Enable = 'off';
                    app.hmEditField.Enable = 'off';
                    app.rmEditField.Enable = 'off';
                    
                case 'Rectangle'
                    app.Image.ImageSource = 'Rectangle.png';
                    app.amEditField.Enable = 'off';
                    app.bmEditField.Enable = 'on';
                    app.hmEditField.Enable = 'on';
                    app.rmEditField.Enable = 'off';
                    
                case 'Circle'
                    app.Image.ImageSource = 'Circle.png';
                    app.amEditField.Enable = 'off';
                    app.bmEditField.Enable = 'off';
                    app.hmEditField.Enable = 'off';
                    app.rmEditField.Enable = 'on';
                    
            end
        end

        % Value changed function: rmEditField
        function rmEditFieldValueChanged(app, event)
            r = app.rmEditField.Value;
            app.MomentofInertiaImEditField.Value = (pi*r^4)/4;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.MomentMNmEditField.Value = event.Value;
            RUNSIMULATIONButtonPushed(app, [])          % update plots
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            app.DistributedLoadFNmEditField.Value = event.Value;
            RUNSIMULATIONButtonPushed(app, [])          % update plots
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            app.ForcefNEditField.Value = event.Value;
            RUNSIMULATIONButtonPushed(app, [])          % update plots
        end

        % Value changing function: Slider_4
        function Slider_4ValueChanging(app, event)
            app.ForcefNEditField_2.Value = event.Value;
            RUNSIMULATIONButtonPushed(app, [])          % update plots
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % ----- Get units BEFORE changing anything -----
            oldUnit = app.unit;                 % current stored unit
            newUnit = app.UnitsDropDown.Value;  % user-selected unit
        
            % ----- Read numeric values from edit fields -----
            L  = app.LengthLmEditField.Value;
            E  = app.YoungsModulusENmEditField.Value;
            M  = app.MomentMNmEditField.Value;
            F1 = app.DistributedLoadFNmEditField.Value;
            f1 = app.ForcefNEditField.Value;
            f3 = app.ForcefNEditField_2.Value;
            a  = app.amEditField.Value;
            r  = app.rmEditField.Value;
            h  = app.hmEditField.Value;
            b  = app.bmEditField.Value;
            I  = app.MomentofInertiaImEditField.Value;

        
            % ----- Convert oldUnit -> MKS -> newUnit -----
            [L,E,M,F1,f1,f3,a,r,h,b,I] = convertToMKS(app, oldUnit, ...
                L,E,M,F1,f1,f3,a,r,h,b,I);
            
            [L,E,M,F1,f1,f3,a,r,h,b,I] = convertFromMKS(app, newUnit, ...
                L,E,M,F1,f1,f3,a,r,h,b,I);
        
            % ----- Update edit fields with converted values -----
            app.LengthLmEditField.Value             = L;
            app.YoungsModulusENmEditField.Value    = E;
            app.MomentMNmEditField.Value           = M;
            app.DistributedLoadFNmEditField.Value  = F1;
            app.ForcefNEditField.Value             = f1;
            app.ForcefNEditField_2.Value             = f3;
            app.amEditField.Value                  = a;
            app.rmEditField.Value                = r;
            app.hmEditField.Value                  = h;
            app.bmEditField.Value                  = b;
            app.MomentofInertiaImEditField.Value  = I;
            

            % ----- Update labels for the NEW unit -----
            switch newUnit
                case 'MKS'
                    app.YoungsModulusENmm2Label.Text           = 'Young''s Modulus, E (N/m²)';
                    app.LengthLmLabel.Text            = 'Length, L (m)';
                    app.MomentMNm2Label.Text                   = 'Moment, M (N·m)';
                    app.DistributedLoadF1NmLabel.Text          = 'Distributed Load, F₁ (N/m)';
                    app.ForcefNEditFieldLabel.Text            = 'Force, f₁ (N)';
                    app.ForcefNEditField_2Label.Text            = 'Force, f₃ (N)';
                    app.amEditFieldLabel.Text                 = 'a (m)';
                    app.rmEditFieldLabel.Text               = 'r (m)';
                    app.hmEditFieldLabel.Text                 = 'h (m)';
                    app.bmEditFieldLabel.Text                 = 'b (m)';
                    app.MomentofInertiaImEditFieldLabel.Text = 'Moment of Inertia, I (m⁴)';
        
                case 'IPS'
                    app.YoungsModulusENmm2Label.Text           = 'Young''s Modulus, E (psi)';
                    app.LengthLmLabel.Text            = 'Length, L (in)';
                    app.MomentMNm2Label.Text                   = 'Moment, M (lbf·in)';
                    app.DistributedLoadF1NmLabel.Text          = 'Distributed Load, F₁ (lbf/in)';
                    app.ForcefNEditFieldLabel.Text            = 'Force, f₁ (lbf)';
                    app.ForcefNEditField_2Label.Text            = 'Force, f₃ (lbf)';
                    app.amEditFieldLabel.Text                 = 'a (in)';
                    app.rmEditFieldLabel.Text               = 'r (in)';
                    app.hmEditFieldLabel.Text                 = 'h (in)';
                    app.bmEditFieldLabel.Text                 = 'b (in)';
                    app.MomentofInertiaImEditFieldLabel.Text = 'Moment of Inertia, I (in⁴)';
        
                case 'CGS'
                    app.YoungsModulusENmm2Label.Text           = 'Young''s Modulus, E (dyne/cm²)';
                    app.LengthLmLabel.Text            = 'Length, L (cm)';
                    app.MomentMNm2Label.Text                   = 'Moment, M (dyne·cm)';
                    app.DistributedLoadF1NmLabel.Text          = 'Distributed Load, F₁ (dyne/cm)';
                    app.ForcefNEditFieldLabel.Text            = 'Force, f₁ (dyne)';
                    app.ForcefNEditField_2Label.Text            = 'Force, f₃ (dyne)';
                    app.amEditFieldLabel.Text                 = 'a (cm)';
                    app.rmEditFieldLabel.Text               = 'r (cm)';
                    app.hmEditFieldLabel.Text                 = 'h (cm)';
                    app.bmEditFieldLabel.Text                 = 'b (cm)';
                    app.MomentofInertiaImEditFieldLabel.Text = 'Moment of Inertia, I (cm⁴)';
            end
        
            % ----- Finally store the new unit -----
            app.unit = newUnit;
            MomentMNmEditFieldValueChanged(app,[]);
            DistributedLoadFNmEditFieldValueChanged(app,[]);
            ForcefNEditFieldValueChanged2(app,[]);
            ForcefNEditField_2ValueChanged2(app,[]);
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
            % ---- Check if plots exist ----
            if isempty(app.UIAxes.Children) && isempty(app.UIAxes_2.Children) && isempty(app.UIAxes_3.Children)
                uialert(app.UIFigure, 'No plots detected. Please generate plots before saving.', 'No Plots Found');
                return;
            end
           
            % ---- Export each UIAxes as high-quality temporary images ----
            tempDir = tempname;
            mkdir(tempDir);
        
            exportgraphics(app.UIAxes,   fullfile(tempDir, 'deflection.png'), 'Resolution', 600);
            exportgraphics(app.UIAxes_2, fullfile(tempDir, 'shear.png'),      'Resolution', 600);
            exportgraphics(app.UIAxes_3, fullfile(tempDir, 'moment.png'),     'Resolution', 600);
        
            % ---- Read them back ----
            img1 = imread(fullfile(tempDir, 'deflection.png'));
            img2 = imread(fullfile(tempDir, 'shear.png'));
            img3 = imread(fullfile(tempDir, 'moment.png'));
        
            % ---- Create a new figure for subplots ----
            fig = figure('Visible', 'on', 'Position', [100 100 900 1100]);

            % % ---- Add summary section if required as a report----
            % summaryText = sprintf([ ...
            %     'Beam Analysis Summary\n\n', ...
            %     'Length: %.3f m\n', ...
            %     'Elastic Modulus: %.3f GPa\n', ...
            %     'Moment of Inertia: %.3e m^4\n', ...
            %     'Distributed Load: %.3f N/m\n', ...
            %     'Moment: %.3f N·m\n', ...
            %     'f1: %.3f N\n', ...
            %     'f3: %.3f N\n'], ...
            %     app.LengthLmEditField.Value, ...
            %     app.YoungsModulusENmEditField.Value, ...
            %     app.MomentofInertiaImEditField.Value, ...
            %     app.DistributedLoadFNmEditField.Value, ...
            %     app.MomentMNmEditField.Value, ...
            %     app.ForcefNEditField.Value, ...
            %     app.ForcefNEditField_2.Value);
            % 
            % 
            % % Display summary as text above the plots
            % % Subplot summary
            % axSummary = subplot(4,1,1);
            % axis(axSummary, 'off');
            % text(0, 0.5, summaryText, 'FontSize', 8, 'VerticalAlignment', 'middle', ...
            %      'HorizontalAlignment', 'center', 'FontName', 'Courier', 'Units', 'normalized');


            % Subplot 1
            ax1 = subplot(3,1,1);
            imshow(img1, 'Parent', ax1);
            %(ax1, 'Beam Deflection', 'FontWeight', 'bold');

            % Subplot 2
            ax2 = subplot(3,1,2);
            imshow(img2, 'Parent', ax2);
            %title(ax2, 'Shear Force Diagram', 'FontWeight', 'bold');

            % Subplot 3
            ax3 = subplot(3,1,3);
            imshow(img3, 'Parent', ax3);
            %title(ax3, 'Bending Moment Diagram', 'FontWeight', 'bold');


            % ---- Ask user where to save ----
            [file, path] = uiputfile({'*.png';'*.jpg'}, 'Save Plots As');
            
            if isequal(file,0) || isequal(path,0)
                % user pressed cancel
                disp('User canceled saving.');
            else
                % Save figure
                exportgraphics(fig, fullfile(path,file),'Resolution',600);
                disp(['Plot saved to: ' fullfile(path,file)]);
            end
            
            % ---- Cleanup ----
            close(fig);
            rmdir(tempDir, 's');
        
            disp(['✅ All plots saved to: ' path]);
        end

        % Button pushed function: ClearFieldsButton
        function ClearFieldsButtonPushed(app, event)
            % Reset all numeric fields to 0
            app.LengthLmEditField.Value            = 0;
            app.YoungsModulusENmEditField.Value   = 0;
            app.MomentMNmEditField.Value          = 0;
            app.DistributedLoadFNmEditField.Value = 0;
            app.ForcefNEditField.Value            = 0;
            app.ForcefNEditField_2.Value            = 0;
            app.amEditField.Value                 = 0;
            app.rmEditField.Value               = 0;
            app.hmEditField.Value                 = 0;
            app.bmEditField.Value                 = 0;
            app.MomentofInertiaImEditField.Value = 0;
            MomentMNmEditFieldValueChanged(app,[]);
            DistributedLoadFNmEditFieldValueChanged(app,[]);
            ForcefNEditFieldValueChanged2(app,[]);
            ForcefNEditField_2ValueChanged2(app,[]);
            startupFcn(app);
        end

        % Value changed function: ShearArrowsSwitch
        function ShearArrowsSwitchValueChanged(app, event)
            RUNSIMULATIONButtonPushed(app, []) 
        end

        % Value changed function: MomentMNmEditField
        function MomentMNmEditFieldValueChanged(app, event)
            M=app.MomentMNmEditField.Value;
            updateSliderLimitswithmean(app, M, app.Slider, true);
        end

        % Value changed function: DistributedLoadFNmEditField
        function DistributedLoadFNmEditFieldValueChanged(app, event)
            F1 = app.DistributedLoadFNmEditField.Value;
            updateSliderLimitswithmean(app,F1, app.Slider_2,true);
        end

        % Value changed function: ForcefNEditField
        function ForcefNEditFieldValueChanged2(app, event)
            f1 = app.ForcefNEditField.Value;
            updateSliderLimitswithmean(app,f1, app.Slider_3, true);
        end

        % Value changed function: ForcefNEditField_2
        function ForcefNEditField_2ValueChanged2(app, event)
            f3=app.ForcefNEditField_2.Value;
            updateSliderLimitswithmean(app,f3, app.Slider_4, true);
        end

        % Value changed function: DeformationSwitch
        function DeformationSwitchValueChanged(app, event)
            RUNSIMULATIONButtonPushed(app, []) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DSTATICRODMODELGUILabel = uilabel(app.UIFigure);
            app.DSTATICRODMODELGUILabel.Text = '2D STATIC ROD MODEL GUI';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DeflectionTab = uitab(app.TabGroup);
            app.DeflectionTab.Title = 'Deflection';
            app.UIAxes = uiaxes(app.DeflectionTab);
            title(app.UIAxes, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.ShearArrowsLabel = uilabel(app.DeflectionTab);
            app.ShearArrowsLabel.Text = 'Shear Arrows';
            app.ShearArrowsSwitch = uiswitch(app.DeflectionTab, 'rocker');
            app.ShearArrowsSwitch.Items = {'Show', 'Hide'};
            app.ShearArrowsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShearArrowsSwitchValueChanged, true);
            app.ShearArrowsSwitch.Value = 'Show';
            app.DeformationSwitchLabel = uilabel(app.DeflectionTab);
            app.DeformationSwitchLabel.Text = 'Deformation';
            app.DeformationSwitch = uiswitch(app.DeflectionTab, 'rocker');
            app.DeformationSwitch.Items = {'Default', 'True'};
            app.DeformationSwitch.ValueChangedFcn = createCallbackFcn(app, @DeformationSwitchValueChanged, true);
            app.DeformationSwitch.Value = 'Default';
            app.ShearForceDiagramTab = uitab(app.TabGroup);
            app.ShearForceDiagramTab.Title = 'Shear Force Diagram';
            app.UIAxes_2 = uiaxes(app.ShearForceDiagramTab);
            title(app.UIAxes_2, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes_2, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes_2, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes_2, 'Z', 'Interpreter', 'latex')
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.BendingMomentDiagramTab = uitab(app.TabGroup);
            app.BendingMomentDiagramTab.Title = 'Bending Moment Diagram';
            app.UIAxes_3 = uiaxes(app.BendingMomentDiagramTab);
            title(app.UIAxes_3, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes_3, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes_3, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes_3, 'Z', 'Interpreter', 'latex')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RUNSIMULATIONButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ResetPlotsButton = uibutton(app.UIFigure, 'push');
            app.ResetPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotsButtonPushed, true);
            app.ResetPlotsButton.Text = 'Reset Plots';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.MaterialTab = uitab(app.TabGroup2);
            app.MaterialTab.Title = 'Material ->';
            app.MATERIALDropDownLabel = uilabel(app.MaterialTab);
            app.MATERIALDropDownLabel.Text = 'MATERIAL';
            app.MATERIALDropDown = uidropdown(app.MaterialTab);
            app.MATERIALDropDown.Items = {'Select', 'Structural Steel', 'Aluminum Alloy', 'Stainless Steel (304)', 'Titanium Alloy (Ti-6Al-4V)', 'Material5.'};
            app.MATERIALDropDown.ValueChangedFcn = createCallbackFcn(app, @MATERIALDropDownValueChanged, true);
            app.MATERIALDropDown.Value = 'Select';
            app.YoungsModulusENmm2Label = uilabel(app.MaterialTab);
            app.YoungsModulusENmm2Label.Text = 'Young''s Modulus, E (N/m²)';
            app.YoungsModulusENmEditField = uieditfield(app.MaterialTab, 'numeric');
            app.YoungsModulusENmEditField.Limits = [0 Inf];
            app.YoungsModulusENmEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusENmEditFieldValueChanged, true);
            app.LengthLmLabel = uilabel(app.MaterialTab);
            app.LengthLmLabel.Text = 'Length, L (m)';
            app.LengthLmEditField = uieditfield(app.MaterialTab, 'numeric');
            app.LengthLmEditField.Limits = [0 Inf];
            app.LengthLmEditField.ValueChangedFcn = createCallbackFcn(app, @LengthLmEditFieldValueChanged, true);
            app.CrossSectionTab = uitab(app.TabGroup2);
            app.CrossSectionTab.Title = 'Cross-Section ->';
            app.XsectionDropDown = uidropdown(app.CrossSectionTab);
            app.XsectionDropDown.Items = {'Select', 'Square', 'Rectangle', 'Circle'};
            app.XsectionDropDown.ValueChangedFcn = createCallbackFcn(app, @XsectionDropDownValueChanged, true);
            app.XsectionDropDown.Value = 'Select';
            app.Image = uiimage(app.CrossSectionTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @XsectionDropDownValueChanged, true);
            app.hmEditFieldLabel = uilabel(app.CrossSectionTab);
            app.hmEditFieldLabel.Text = 'h (m)';
            app.hmEditField = uieditfield(app.CrossSectionTab, 'numeric');
            app.hmEditField.Limits = [0 Inf];
            app.hmEditField.ValueChangedFcn = createCallbackFcn(app, @hmEditFieldValueChanged, true);
            app.hmEditField.Enable = 'off';
            app.bmEditFieldLabel = uilabel(app.CrossSectionTab);
            app.bmEditFieldLabel.Text = 'b (m)';
            app.bmEditField = uieditfield(app.CrossSectionTab, 'numeric');
            app.bmEditField.Limits = [0 Inf];
            app.bmEditField.ValueChangedFcn = createCallbackFcn(app, @bmEditFieldValueChanged, true);
            app.bmEditField.Enable = 'off';
            app.amEditFieldLabel = uilabel(app.CrossSectionTab);
            app.amEditFieldLabel.Text = 'a (m)';
            app.amEditField = uieditfield(app.CrossSectionTab, 'numeric');
            app.amEditField.Limits = [0 Inf];
            app.amEditField.ValueChangedFcn = createCallbackFcn(app, @amEditFieldValueChanged, true);
            app.amEditField.Enable = 'off';
            app.MomentofInertiaImEditFieldLabel = uilabel(app.CrossSectionTab);
            app.MomentofInertiaImEditFieldLabel.Text = 'Moment of Inertia, I (m⁴)';
            app.MomentofInertiaImEditField = uieditfield(app.CrossSectionTab, 'numeric');
            app.MomentofInertiaImEditField.Limits = [0 Inf];
            app.MomentofInertiaImEditField.ValueChangedFcn = createCallbackFcn(app, @MomentofInertiaImEditFieldValueChanged, true);
            app.rmEditFieldLabel = uilabel(app.CrossSectionTab);
            app.rmEditFieldLabel.Text = 'r (m)';
            app.rmEditField = uieditfield(app.CrossSectionTab, 'numeric');
            app.rmEditField.Limits = [0 Inf];
            app.rmEditField.ValueChangedFcn = createCallbackFcn(app, @rmEditFieldValueChanged, true);
            app.rmEditField.Enable = 'off';
            app.LoadConfigurationTab = uitab(app.TabGroup2);
            app.LoadConfigurationTab.Title = 'Load Configuration';
            app.MomentMNm2Label = uilabel(app.LoadConfigurationTab);
            app.MomentMNm2Label.Text = 'Moment, M (N·m)';
            app.MomentMNmEditField = uieditfield(app.LoadConfigurationTab, 'numeric');
            app.MomentMNmEditField.AllowEmpty = 'on';
            app.MomentMNmEditField.ValueChangedFcn = createCallbackFcn(app, @MomentMNmEditFieldValueChanged, true);
            app.Slider = uislider(app.LoadConfigurationTab);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1e-06;
            app.DistributedLoadF1NmLabel = uilabel(app.LoadConfigurationTab);
            app.DistributedLoadF1NmLabel.Text = 'Distributed Load, F₁ (N/m)';
            app.DistributedLoadFNmEditField = uieditfield(app.LoadConfigurationTab, 'numeric');
            app.DistributedLoadFNmEditField.AllowEmpty = 'on';
            app.DistributedLoadFNmEditField.ValueChangedFcn = createCallbackFcn(app, @DistributedLoadFNmEditFieldValueChanged, true);
            app.Slider_2 = uislider(app.LoadConfigurationTab);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.Value = 1e-06;
            app.ForcefNEditFieldLabel = uilabel(app.LoadConfigurationTab);
            app.ForcefNEditFieldLabel.Text = 'Force, f₁ (N)';
            app.ForcefNEditField = uieditfield(app.LoadConfigurationTab, 'numeric');
            app.ForcefNEditField.AllowEmpty = 'on';
            app.ForcefNEditField.ValueChangedFcn = createCallbackFcn(app, @ForcefNEditFieldValueChanged2, true);
            app.Slider_3 = uislider(app.LoadConfigurationTab);
            app.Slider_3.Limits = [0 1];
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Slider_3.Value = 1e-06;
            app.ForcefNEditField_2Label = uilabel(app.LoadConfigurationTab);
            app.ForcefNEditField_2Label.Text = 'Force, f₃ (N)';
            app.ForcefNEditField_2 = uieditfield(app.LoadConfigurationTab, 'numeric');
            app.ForcefNEditField_2.AllowEmpty = 'on';
            app.ForcefNEditField_2.ValueChangedFcn = createCallbackFcn(app, @ForcefNEditField_2ValueChanged2, true);
            app.Image2 = uiimage(app.LoadConfigurationTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'rod_2d_image.png');
            app.Slider_4 = uislider(app.LoadConfigurationTab);
            app.Slider_4.Limits = [0 1];
            app.Slider_4.ValueChangingFcn = createCallbackFcn(app, @Slider_4ValueChanging, true);
            app.Slider_4.Value = 1e-06;
            app.SavePlotsButton = uibutton(app.UIFigure, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Text = 'Save Plots';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'MKS', 'IPS', 'CGS'};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'MKS';
            app.ClearFieldsButton = uibutton(app.UIFigure, 'push');
            app.ClearFieldsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFieldsButtonPushed, true);
            app.ClearFieldsButton.Text = 'Clear Fields';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = code2D_advance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end