classdef code3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadingScenarioeeanderepresentXYandZaxisrespectivelyLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        DStaticRodModelGUILabel    matlab.ui.control.Label
        DistributedMomentQNLabel   matlab.ui.control.Label
        DistributedForceFNmmLabel  matlab.ui.control.Label
        ForcefNLabel               matlab.ui.control.Label
        MomentMNmmLabel            matlab.ui.control.Label
        StiffnessMatrixSNmmLabel   matlab.ui.control.Label
        SEditField_9               matlab.ui.control.NumericEditField
        SEditField_9Label          matlab.ui.control.Label
        SEditField_8               matlab.ui.control.NumericEditField
        SEditField_8Label          matlab.ui.control.Label
        SEditField_7               matlab.ui.control.NumericEditField
        SEditField_7Label          matlab.ui.control.Label
        SEditField_6               matlab.ui.control.NumericEditField
        SEditField_6Label          matlab.ui.control.Label
        SEditField_5               matlab.ui.control.NumericEditField
        SEditField_5Label          matlab.ui.control.Label
        SEditField_4               matlab.ui.control.NumericEditField
        SLabel                     matlab.ui.control.Label
        SEditField_3               matlab.ui.control.NumericEditField
        SEditField_3Label          matlab.ui.control.Label
        SEditField_2               matlab.ui.control.NumericEditField
        SEditField_2Label          matlab.ui.control.Label
        SEditField                 matlab.ui.control.NumericEditField
        SEditFieldLabel            matlab.ui.control.Label
        QEditField_3               matlab.ui.control.NumericEditField
        QEditField_3Label          matlab.ui.control.Label
        QEditField_2               matlab.ui.control.NumericEditField
        QEditField_2Label          matlab.ui.control.Label
        QEditField                 matlab.ui.control.NumericEditField
        QEditFieldLabel            matlab.ui.control.Label
        FEditField_3               matlab.ui.control.NumericEditField
        FEditField_3Label          matlab.ui.control.Label
        FEditField_2               matlab.ui.control.NumericEditField
        FEditField_2Label          matlab.ui.control.Label
        FEditField                 matlab.ui.control.NumericEditField
        FEditFieldLabel            matlab.ui.control.Label
        fEditField_2               matlab.ui.control.NumericEditField
        fEditField_2Label          matlab.ui.control.Label
        MEditField_3               matlab.ui.control.NumericEditField
        MEditField_3Label          matlab.ui.control.Label
        MEditField_2               matlab.ui.control.NumericEditField
        MLabel                     matlab.ui.control.Label
        RunSimulationButton        matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        fEditField_3               matlab.ui.control.NumericEditField
        fEditField_3Label          matlab.ui.control.Label
        fEditField                 matlab.ui.control.NumericEditField
        fEditFieldLabel            matlab.ui.control.Label
        MEditField                 matlab.ui.control.NumericEditField
        MEditFieldLabel            matlab.ui.control.Label
        LengthLmmEditField         matlab.ui.control.NumericEditField
        LengthLmmEditFieldLabel    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            cla(app.UIAxes);
            % Get input value
            Length = app.LengthLmmEditField.Value;
            M1 = app.MEditField.Value;
            M2 = app.MEditField_2.Value;
            M3 = app.MEditField_3.Value;
            f1 = app.fEditField.Value;
            f2 = app.fEditField_2.Value;
            f3 = app.fEditField_3.Value;
            F1 = app.FEditField.Value;
            F2 = app.FEditField_2.Value;
            F3 = app.FEditField_3.Value;
            Q1 = app.QEditField.Value;
            Q2 = app.QEditField_2.Value;
            Q3 = app.QEditField_3.Value;
            S11 = app.SEditField.Value;
            S12 = app.SEditField_2.Value;
            S13 = app.SEditField_3.Value;
            S21 = app.SEditField_4.Value;
            S22 = app.SEditField_5.Value;
            S23 = app.SEditField_6.Value;
            S31 = app.SEditField_7.Value;
            S32 = app.SEditField_8.Value;
            S33 = app.SEditField_9.Value;

           D=[S11,S12,S13;S21,S22,S23;S31,S32,S33];

           kappa=D\[M1;M2;M3];
           kappa1=kappa(1);
           kappa2=kappa(2);
           kappa3=kappa(3);


A=[D(1,1),D(1,2),D(1,3),0,0,0;D(2,1),D(2,2),D(2,3),0,0,0;D(3,1),D(3,2),D(3,3),0,0,0;0,0,0,1,0,0;0,0,0,0,1,0;0,0,0,0,0,1];

%B=inv(A);

% Solving the static equilibrium equations in 3D
% x(1)=kappa1,x(2)=kappa2,x(3)=kappa3, x(4)=f1, x(5)=f2, x(6)=f3
f = @(distance,x) A\[(x(3)*(D(2,1)*x(1)+D(2,2)*x(2)+D(2,3)*x(3))-x(2)*(D(3,1)*x(1)+D(3,2)*x(2)+D(3,3)*x(3))+x(5)-Q1);(x(1)*(D(3,1)*x(1)+D(3,2)*x(2)+D(3,3)*x(3))-x(3)*(D(1,1)*x(1)+D(1,2)*x(2)+D(1,3)*x(3))-x(4)-Q2);(x(2)*(D(1,1)*x(1)+D(1,2)*x(2)+D(1,3)*x(3))-x(1)*(D(2,1)*x(1)+D(2,2)*x(2)+D(2,3)*x(3))-Q3);..........
    x(3)*x(5)-x(2)*x(6)-F1;x(1)*x(6)-x(3)*x(4)-F2;x(2)*x(4)-x(1)*x(5)-F3];

% initial condition=[kappa1,kappa2,kappa3,f1,f2,f3]

[distance,xa] = ode45(f,[0 Length],[kappa1 kappa2 kappa3 f1 f2 f3]);

len=length(xa);
% Reversing the kappa and s values
xa(:,1)=flipud(xa(:,1));
xa(:,2)=flipud(xa(:,2));
xa(:,3)=flipud(xa(:,3));
s=Length*ones(len,1)-flipud(distance);

L=[1,0,0;0,1,0;0,0,1]; % initial transformation matrix

t=[];

for i=1:len-1
    % Equation A.6 from JAM paper
    theta=((s(i+1)-s(i))/2).*([0,-xa(i,3),xa(i,2);xa(i,3),0,-xa(i,1);-xa(i,2),xa(i,1),0]+[0,-xa(i+1,3),xa(i+1,2);xa(i+1,3),0,-xa(i+1,1);-xa(i+1,2),xa(i+1,1),0]);
  
    % Equation A.5 from JAM paper
    L_next=expm(-theta)*L;

    % Equation A.3 from JAM paper
    t_iner=transpose(L_next)*[0,0,1]';

    t=[t,t_iner];% Storing all the unit tangent vector t(s) in global frame

    L=L_next;
    

end

a=[0,0,1];
t=[a',t]; % At clamped end t=[0 0 1];

t=t';
 R=cumtrapz(s,t);% Solving eq A.1 from JAM paper

            % Plot XZ View
            hold(app.UIAxes, 'on');
            %plot(app.UIAxes, R(:,3),R(:,1), 'LineWidth', 2);
            plot3(R(:,3),R(:,2),R(:,1),'Parent',app.UIAxes,'Color','r','LineWidth',2);
            
            title(app.UIAxes, 'Nonlinear Beam Deflection');
            xlabel(app.UIAxes, 'X (mm)');
            ylabel(app.UIAxes, 'Y (mm)');
            zlabel(app.UIAxes, 'Z (mm)');
            view(app.UIAxes,[20,30]);
            %legend(app.UIAxes, 'NonLinear Theory',Location='north');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Clear the plots and reset inputs
            cla(app.UIAxes);
          
            % app.ENmm2EditField.Value = 1;
            % app.Imm4EditField.Value = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title', 'Interpreter', 'latex')
            xlabel(app.UIAxes, 'X', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            app.LengthLmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthLmmEditFieldLabel.Text = 'Length, L (mm)';
            app.LengthLmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M₁';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f₁';
            app.fEditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditField_3Label = uilabel(app.UIFigure);
            app.fEditField_3Label.Text = 'f₃';
            app.fEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M₂';
            app.MEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.MEditField_3Label = uilabel(app.UIFigure);
            app.MEditField_3Label.Text = 'M₃';
            app.MEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.fEditField_2Label = uilabel(app.UIFigure);
            app.fEditField_2Label.Text = 'f₂';
            app.fEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FEditFieldLabel = uilabel(app.UIFigure);
            app.FEditFieldLabel.Text = 'F₁';
            app.FEditField = uieditfield(app.UIFigure, 'numeric');
            app.FEditField_2Label = uilabel(app.UIFigure);
            app.FEditField_2Label.Text = 'F₂';
            app.FEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FEditField_3Label = uilabel(app.UIFigure);
            app.FEditField_3Label.Text = 'F₃';
            app.FEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q₁';
            app.QEditField = uieditfield(app.UIFigure, 'numeric');
            app.QEditField_2Label = uilabel(app.UIFigure);
            app.QEditField_2Label.Text = 'Q₂';
            app.QEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.QEditField_3Label = uilabel(app.UIFigure);
            app.QEditField_3Label.Text = 'Q₃';
            app.QEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SEditFieldLabel = uilabel(app.UIFigure);
            app.SEditFieldLabel.Text = 'S₁₁';
            app.SEditField = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_2Label = uilabel(app.UIFigure);
            app.SEditField_2Label.Text = 'S₁₂';
            app.SEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_3Label = uilabel(app.UIFigure);
            app.SEditField_3Label.Text = 'S₁₃';
            app.SEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.SLabel = uilabel(app.UIFigure);
            app.SLabel.Text = 'S₂₁';
            app.SEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_5Label = uilabel(app.UIFigure);
            app.SEditField_5Label.Text = 'S₂₂';
            app.SEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_6Label = uilabel(app.UIFigure);
            app.SEditField_6Label.Text = 'S₂₃';
            app.SEditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_7Label = uilabel(app.UIFigure);
            app.SEditField_7Label.Text = 'S₃₁';
            app.SEditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_8Label = uilabel(app.UIFigure);
            app.SEditField_8Label.Text = 'S₃₂';
            app.SEditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.SEditField_9Label = uilabel(app.UIFigure);
            app.SEditField_9Label.Text = 'S₃₃';
            app.SEditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.StiffnessMatrixSNmmLabel = uilabel(app.UIFigure);
            app.StiffnessMatrixSNmmLabel.Text = 'Stiffness Matrix, [S] (N·mm²)';
            app.MomentMNmmLabel = uilabel(app.UIFigure);
            app.MomentMNmmLabel.Text = 'Moment, {M} (N·mm)';
            app.ForcefNLabel = uilabel(app.UIFigure);
            app.ForcefNLabel.Text = 'Force, {f} (N)';
            app.DistributedForceFNmmLabel = uilabel(app.UIFigure);
            app.DistributedForceFNmmLabel.Text = 'Distributed Force, {F} (N/mm)';
            app.DistributedMomentQNLabel = uilabel(app.UIFigure);
            app.DistributedMomentQNLabel.Text = 'Distributed Moment, {Q} (N)';
            app.DStaticRodModelGUILabel = uilabel(app.UIFigure);
            app.DStaticRodModelGUILabel.Text = '3D Static Rod Model GUI';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'rod_graphic.png');
            app.LoadingScenarioeeanderepresentXYandZaxisrespectivelyLabel = uilabel(app.UIFigure);
            app.LoadingScenarioeeanderepresentXYandZaxisrespectivelyLabel.Text = {'Loading Scenario: ê₁, ê₂ and ê₃ represent '; 'X, Y and Z axis respectively'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = code3D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end