classdef code2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DStaticRodModelGUILabel       matlab.ui.control.Label
        DistributedLoadFNmmEditField  matlab.ui.control.NumericEditField
        DistributedLoadFNmmEditFieldLabel  matlab.ui.control.Label
        RunSimulationButton           matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        ForcefNEditField_2            matlab.ui.control.NumericEditField
        ForceF_3NLabel                matlab.ui.control.Label
        ForcefNEditField              matlab.ui.control.NumericEditField
        ForcefNLabel                  matlab.ui.control.Label
        MomentMNmmEditField           matlab.ui.control.NumericEditField
        MomentMNmmLabel               matlab.ui.control.Label
        LengthLmmEditField            matlab.ui.control.NumericEditField
        LengthLmmEditFieldLabel       matlab.ui.control.Label
        MomentofInertiaImmEditField   matlab.ui.control.NumericEditField
        Imm4Label                     matlab.ui.control.Label
        YoungsModulusENmmEditField    matlab.ui.control.NumericEditField
        YoungsModulusENmmEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)

        function initialbeam(app)
            set(0,'defaultTextInterpreter','latex')

            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');

            % Plot beam
            plot(app.UIAxes,[0, 10], [0, 0], 'k', 'LineWidth', 3); % Beam
            fill(app.UIAxes,[-0.2, 0, 0, -0.2], [-0.5, -0.5, 0.5, 0.5], 'k'); % Clamped end

            % Indicate distributed load
            x = linspace(0, 10, 20);
            y = -0.6 * ones(size(x));
            quiver(app.UIAxes, x, y, zeros(size(x)), 1 * ones(size(x)), 0.2, 'b', 'LineWidth', 1);
            text(app.UIAxes, 10/2, -0.8, '$F_1$', 'Color', 'b', 'FontSize', 12);

            % Indicate forces at free end
            quiver(app.UIAxes, 10, 1.4, 0, -5, 0.25, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
            text(app.UIAxes, 10+0.1, 1.2, '$f_1$', 'Color', 'r', 'FontSize', 12);
            quiver(app.UIAxes, 10+1.4, 0, -5, 0, 0.25, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.5);
            text(app.UIAxes, 10+1, 0.3, '$f_3$', 'Color', 'r', 'FontSize', 12);

            % Indicate moment at free end
            theta = linspace(pi/2, 3*pi/2, 20);
            arc_x = 10 + 0.5 * cos(theta);
            arc_y = 0.5 * sin(theta);
            plot(app.UIAxes, arc_x, arc_y, 'r', 'LineWidth', 2);
            quiver(app.UIAxes, 10-0.5 * cos(3*pi/2), 0.5 * sin(3*pi/2), 2, 0, 0.2, ...
                   'r', 'LineWidth', 2, 'MaxHeadSize', 1);
            text(app.UIAxes, 10+0.2, -0.8, '$M$', 'Color', 'r', 'FontSize', 12);

            % Indicate material properties
            text(app.UIAxes, 10/4, 0.3, '$E, I$', 'FontSize', 12, 'FontWeight', 'bold');

            % Indicate length with bidirectional arrow
            plot(app.UIAxes, [0, 10], [-1.25, -1.25], 'k--');
            quiver(app.UIAxes, 0, -1.25, 10, 0, 0, 'k', 'LineWidth', 1, 'MaxHeadSize', 0.1);
            quiver(app.UIAxes, 10, -1.25, -10, 0, 0, 'k', 'LineWidth', 1, 'MaxHeadSize', 0.1);
            text(app.UIAxes, 10/2, -1.5, '$L$', 'FontSize', 12);

            grid(app.UIAxes, 'off');
            axis(app.UIAxes, 'off');
            hold(app.UIAxes, 'off');
            title(app.UIAxes, 'Beam Deflection');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initialbeam(app);
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            cla(app.UIAxes);
            % Get input values
            E = app.YoungsModulusENmmEditField.Value;
            I = app.MomentofInertiaImmEditField.Value;
            Length = app.LengthLmmEditField.Value;
            M = app.MomentMNmmEditField.Value;
            f1 = app.ForcefNEditField.Value;
            f3 = app.ForcefNEditField_2.Value;
            wf=app.DistributedLoadFNmmEditField.Value;

            kappa = M/(E*I);
            % Define ODE
            f = @(distance, x) [-x(2)/(E*I); -x(3)*x(1)-wf; x(2)*x(1)];
            
            % Initial conditions: [kappa, f1, f3]
            % f1=shear force, f3=axial force
            [distance, xa] = ode45(f, [0 Length], [kappa f1 f3]);

            len = length(xa);

            % Reversing distance and kappa for integration from clamped end
            s = Length * ones(len, 1) - flipud(distance);
            kappa_final = flipud(xa(:,1));

            % Calculate linear deflection
            le=0:Length/20:Length;
            linear_deflection = ((-f1 * (le.^2).*(3.*Length-le)) ./ (6*E*I))+((kappa/2).*le.*le)+(wf.*le.^2.*(6*Length^2-4*Length.*le+le.^2)/(24*E*I));

            % Calculate phi, Rx, Ry
            phi = cumtrapz(s, kappa_final);
            Rx = cumtrapz(s, cos(phi));
            Ry = cumtrapz(s, sin(phi));

            % Plot Rx vs Ry
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, Rx, Ry, 'LineWidth', 2);
            plot(app.UIAxes, le, linear_deflection, '--g', 'LineWidth', 2);
            title(app.UIAxes, 'Beam Deflection');
            xlabel(app.UIAxes, 'X (mm)');
            ylabel(app.UIAxes, 'Y (mm)');
            legend(app.UIAxes, 'NonLinear Theory', 'Small Deflection Theory');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Clear the plots and reset inputs
            cla(app.UIAxes);
            % app.YoungsModulusENmmEditField.Value = 1;
            % app.MomentofInertiaImmEditField.Value = 1;
            legend(app.UIAxes, 'off');
            initialbeam(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.YoungsModulusENmmEditFieldLabel = uilabel(app.UIFigure);
            app.YoungsModulusENmmEditFieldLabel.Text = 'Young’s Modulus, E (N/mm²)';
            app.YoungsModulusENmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Imm4Label = uilabel(app.UIFigure);
            app.Imm4Label.Text = 'Moment of Inertia, I (mm⁴)';
            app.MomentofInertiaImmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthLmmEditFieldLabel = uilabel(app.UIFigure);
            app.LengthLmmEditFieldLabel.Text = 'Length, L (mm)';
            app.LengthLmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MomentMNmmLabel = uilabel(app.UIFigure);
            app.MomentMNmmLabel.Text = 'Moment, M (N·mm)';
            app.MomentMNmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForcefNLabel = uilabel(app.UIFigure);
            app.ForcefNLabel.Text = 'Force,  f₁ (N)';
            app.ForcefNEditField = uieditfield(app.UIFigure, 'numeric');
            app.ForceF_3NLabel = uilabel(app.UIFigure);
            app.ForceF_3NLabel.Text = 'Force, f₃ (N)';
            app.ForcefNEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.DistributedLoadFNmmEditFieldLabel = uilabel(app.UIFigure);
            app.DistributedLoadFNmmEditFieldLabel.Text = 'Distributed Load, F₁ (N/mm)';
            app.DistributedLoadFNmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DStaticRodModelGUILabel = uilabel(app.UIFigure);
            app.DStaticRodModelGUILabel.Text = '2D Static Rod Model GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = code2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end